classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Matrix_ATextAreaLabel  matlab.ui.control.Label
        Matrix_ATextArea       matlab.ui.control.TextArea
        ABButton               matlab.ui.control.Button
        Matrix_BTextAreaLabel  matlab.ui.control.Label
        Matrix_BTextArea       matlab.ui.control.TextArea
        Label                  matlab.ui.control.Label
        Button                 matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ABButton
        function ABButtonPushed(app, event)
            x = app.Matrix_ATextArea.Value;
            y = app.Matrix_BTextArea.Value;
            if (length(x) ~= length(y))
                app.Label.Text = "check matrix dimensions";
            else
                app.Label.Text = "matrix dimensions fit";
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            x = app.Matrix_ATextArea.Value;
            y = app.Matrix_BTextArea.Value;
            a = ones(length(x),length(strsplit(x{1},' ')));
            for i = 1:length(x)
                a(i,1:end) = str2double(strsplit(x{i},' '));
            end
            b = ones(length(y),length(strsplit(y{1},' ')));
            for i = 1:length(y)
                b(i,1:end) = str2double(strsplit(y{i},' '));
            end
            %app.Label.Text = "Gauss:  " + mat2str(a\b);
            n = size(a);
            n = n(1);
            B = [a b];
            for i = 1:n
                B(i,1:n+1) = B(i,1:n+1)./B(i,i);
                for j=i+1:n
                    B(j,1:n+1) = B(j,1:n+1) - B(i,1:n+1).*B(j,i);
                end
            end
            res = zeros(n,1);
            for k=n:-1:1
                S=0;
                for i=k+1:n
                    S=S+res(i,1)*B(k,i);
                end
                res(k,1)= B(k,n+1)-S;
            end
            
            app.Label.Text = "Gauss:  " + mat2str(res);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            x = app.Matrix_ATextArea.Value;
            y = app.Matrix_BTextArea.Value;
            if (length(x) ~= length(strsplit(x{1},' ')))
                app.Label.Text = "the matrix isn't square";
            else
                a = ones(length(x),length(strsplit(x{1},' ')));
                for i = 1:length(x)
                    a(i,1:end) = str2double(strsplit(x{i},' '));
                end
                b = ones(length(y),length(strsplit(y{1},' ')));
                for i = 1:length(y)
                    b(i,1:end) = str2double(strsplit(y{i},' '));
                end
                z = zeros(size(b));
                D = det(a);
                for i = 1:length(b)
                    m = a;
                    m(:,i) = b;
                    z(i) = det(m)/D;
                end
                app.Label.Text = "Kramer:  " + mat2str(z);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Matrix_ATextAreaLabel = uilabel(app.UIFigure);
            app.Matrix_ATextAreaLabel.Text = 'Matrix_A';
            app.Matrix_ATextArea = uitextarea(app.UIFigure);
            app.ABButton = uibutton(app.UIFigure, 'push');
            app.ABButton.ButtonPushedFcn = createCallbackFcn(app, @ABButtonPushed, true);
            app.ABButton.Text = 'Ввести A и B';
            app.Matrix_BTextAreaLabel = uilabel(app.UIFigure);
            app.Matrix_BTextAreaLabel.Text = 'Matrix_B';
            app.Matrix_BTextArea = uitextarea(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Гаусс';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Крамер';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end